classdef CIMTOOL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ImportNLEVPMenu               matlab.ui.container.Menu
        WorkspaceMenu                 matlab.ui.container.Menu
        FileMenu_2                    matlab.ui.container.Menu
        NLEVPPackMenu                 matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        EigenvaluesMenu               matlab.ui.container.Menu
        MomentsMenu                   matlab.ui.container.Menu
        FigureMenu                    matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        ShiftPatternMenu              matlab.ui.container.Menu
        equispacedMenu                matlab.ui.container.Menu
        randomMenu                    matlab.ui.container.Menu
        PlottingAttributesMenu        matlab.ui.container.Menu
        ComputationMenu               matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        EigSearchEditField            matlab.ui.control.NumericEditField
        EigSearchEditFieldLabel       matlab.ui.control.Label
        IMINEditField                 matlab.ui.control.NumericEditField
        IMINEditFieldLabel            matlab.ui.control.Label
        RMAXEditField                 matlab.ui.control.NumericEditField
        RMAXEditFieldLabel            matlab.ui.control.Label
        RMINEditField                 matlab.ui.control.NumericEditField
        RMINEditFieldLabel            matlab.ui.control.Label
        IMAXEditField                 matlab.ui.control.NumericEditField
        IMAXEditFieldLabel            matlab.ui.control.Label
        PROBLEMLOADEDTextArea         matlab.ui.control.TextArea
        PROBLEMLOADEDTextAreaLabel    matlab.ui.control.Label
        ShiftsButton                  matlab.ui.control.Button
        AxisEqualCheckBox             matlab.ui.control.CheckBox
        RESETVIEWPORTButton           matlab.ui.control.Button
        COMPUTEButton                 matlab.ui.control.Button
        COMPUTATIONALMODEButtonGroup  matlab.ui.container.ButtonGroup
        MPLoewnerButton               matlab.ui.control.ToggleButton
        SPLoewnerButton               matlab.ui.control.ToggleButton
        HankelButton                  matlab.ui.control.ToggleButton
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        NLEVPInformationTab           matlab.ui.container.Tab
        TextArea_3                    matlab.ui.control.TextArea
        ContourTab                    matlab.ui.container.Tab
        ContourParametersButton       matlab.ui.control.Button
        QuadNodesSlider               matlab.ui.control.Slider
        QuadNodesSliderLabel          matlab.ui.control.Label
        QuadratureButtonGroup         matlab.ui.container.ButtonGroup
        NewtonCotesButton             matlab.ui.control.RadioButton
        GaussianButton                matlab.ui.control.RadioButton
        TrapezoidButton               matlab.ui.control.RadioButton
        TypeButtonGroup               matlab.ui.container.ButtonGroup
        RectangleButton               matlab.ui.control.RadioButton
        EllipseButton                 matlab.ui.control.RadioButton
        CircleButton                  matlab.ui.control.RadioButton
        EigenvalueInformationTab      matlab.ui.container.Tab
        TextArea                      matlab.ui.control.TextArea
        ErrorsWarningsTab             matlab.ui.container.Tab
        TextArea_2                    matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %
    end
    
    properties (Access = public)
        NLEVP % structure array that contains NLEVP fields
    end
    
    methods (Access = public)
        %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: NLEVPPackMenu
        function NLEVPPackMenuSelected(app, event)
            % prompt the user for a problem name and comma-separated list
            % of arguments
            app.NLEVPPackMenu.Enable = "off";
            prompt = {"problem","arglist (comma-separated list)"};
            answer = inputdlg(prompt,"NLEVP pack import");
            probstr = answer{1};
            % check first if the passed problem exists in the NLEVP pack
            try
                nlevp(probstr);
            catch PE
                errordlg('Given problem name not found in the NLEVP pack. Check spelling/NLEVP pack version and try again.')
                app.PROBLEMLOADEDTextArea.BackgroundColor="r";
                app.NLEVPPackMenu.Enable="on";
                rethrow(PE)
            end
            % if the problem exists, print its help-string before anything
            % else for user reference
            nlevp_home = which('nlevp');
            nlevp_home = strrep(nlevp_home, 'nlevp.m', '');
            if ispc
                app.TextArea_3.Value=help(sprintf('%sprivate\\%s', nlevp_home, probstr));
            else
                app.TextArea_3.Value=help(sprintf('%sprivate/%s', nlevp_home, probstr));
            end
            % now deal with any NLEVP parameters
            strarglist = split(answer{2},",");
            allfinite=true;
            if (strarglist=="")
                % the problem exists, so this should run without error
                app.NLEVP.arglist=missing;
                app.PROBLEMLOADEDTextArea.Value=sprintf("%s",probstr);
                [app.NLEVP.coeffs,app.NLEVP.fun,app.NLEVP.f] = nlevp(probstr);
            else
                numarglist=num2cell(str2double(strarglist));
                % check that there isn't something like an extra comma,
                % which could incorrectly set an NLEVP parameter
                for i=1:length(numarglist)
                    if ~isfinite(numarglist{i})
                        allfinite=false;
                        warning("Passed parameter %d is not finite.", i)
                    end
                end
                if ~allfinite
                    warndlg("One or more of passed NLEVP parameters is not finite. Please ensure that the passed argument list is correct!")
                    app.PROBLEMLOADEDTextArea.BackgroundColor="y";
                end
                app.PROBLEMLOADEDTextArea.Value=sprintf("%s(%s)",probstr,strjoin(strarglist,","));
                % as far as I can tell, there is no simple way to check
                % that arglist doesn't contain MORE parameters than the
                % nlevp uses...the help string for each nlevp shows the
                % calling convention, but there is no "number" to check
                % against...
                try
                    [app.NLEVP.coeffs,app.NLEVP.fun,app.NLEVP.f] = nlevp(probstr,numarglist{:});
                catch AE
                    errordlg('NLEVP exists, but passed argument list caused an error. Check NLEVP help string and try again.')
                    app.PROBLEMLOADEDTextArea.BackgroundColor="r";
                    app.NLEVPPackMenu.Enable="on";
                    rethrow(AE)
                end
                app.NLEVP.arglist = numarglist;
            end
            app.NLEVP.name = probstr;
            if allfinite
                app.PROBLEMLOADEDTextArea.BackgroundColor="g";
            end
            app.NLEVPPackMenu.Enable="on";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {483, 483};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {212, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportNLEVPMenu = uimenu(app.FileMenu);
            app.ImportNLEVPMenu.Text = 'Import NLEVP';
            app.WorkspaceMenu = uimenu(app.ImportNLEVPMenu);
            app.WorkspaceMenu.Enable = 'off';
            app.WorkspaceMenu.Text = 'Workspace';
            app.FileMenu_2 = uimenu(app.ImportNLEVPMenu);
            app.FileMenu_2.Enable = 'off';
            app.FileMenu_2.Text = 'File';
            app.NLEVPPackMenu = uimenu(app.ImportNLEVPMenu);
            app.NLEVPPackMenu.MenuSelectedFcn = createCallbackFcn(app, @NLEVPPackMenuSelected, true);
            app.NLEVPPackMenu.Text = 'NLEVP Pack';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export...';
            app.EigenvaluesMenu = uimenu(app.ExportMenu);
            app.EigenvaluesMenu.Text = 'Eigenvalues';
            app.MomentsMenu = uimenu(app.ExportMenu);
            app.MomentsMenu.Text = 'Moments';
            app.FigureMenu = uimenu(app.ExportMenu);
            app.FigureMenu.Text = 'Figure';
            app.PreferencesMenu = uimenu(app.UIFigure);
            app.PreferencesMenu.Text = 'Preferences';
            app.ShiftPatternMenu = uimenu(app.PreferencesMenu);
            app.ShiftPatternMenu.Text = 'Shift Pattern';
            app.equispacedMenu = uimenu(app.ShiftPatternMenu);
            app.equispacedMenu.Enable = 'off';
            app.equispacedMenu.Text = 'equispaced';
            app.randomMenu = uimenu(app.ShiftPatternMenu);
            app.randomMenu.Enable = 'off';
            app.randomMenu.Text = 'random';
            app.PlottingAttributesMenu = uimenu(app.PreferencesMenu);
            app.PlottingAttributesMenu.Enable = 'off';
            app.PlottingAttributesMenu.Text = 'Plotting Attributes';
            app.ComputationMenu = uimenu(app.PreferencesMenu);
            app.ComputationMenu.Enable = 'off';
            app.ComputationMenu.Text = 'Computation';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.COMPUTATIONALMODEButtonGroup = uibuttongroup(app.LeftPanel);
            app.COMPUTATIONALMODEButtonGroup.Title = 'COMPUTATIONAL MODE';
            app.HankelButton = uitogglebutton(app.COMPUTATIONALMODEButtonGroup);
            app.HankelButton.Text = 'Hankel';
            app.HankelButton.Value = true;
            app.SPLoewnerButton = uitogglebutton(app.COMPUTATIONALMODEButtonGroup);
            app.SPLoewnerButton.Text = 'SPLoewner';
            app.MPLoewnerButton = uitogglebutton(app.COMPUTATIONALMODEButtonGroup);
            app.MPLoewnerButton.Text = 'MPLoewner';
            app.COMPUTEButton = uibutton(app.LeftPanel, 'push');
            app.COMPUTEButton.Text = 'COMPUTE';
            app.RESETVIEWPORTButton = uibutton(app.LeftPanel, 'push');
            app.RESETVIEWPORTButton.Text = 'RESET VIEWPORT';
            app.AxisEqualCheckBox = uicheckbox(app.LeftPanel);
            app.AxisEqualCheckBox.Text = 'Axis Equal';
            app.ShiftsButton = uibutton(app.LeftPanel, 'push');
            app.ShiftsButton.Enable = 'off';
            app.ShiftsButton.Text = 'Shift(s)';
            app.PROBLEMLOADEDTextAreaLabel = uilabel(app.LeftPanel);
            app.PROBLEMLOADEDTextAreaLabel.Text = 'PROBLEM LOADED';
            app.PROBLEMLOADEDTextArea = uitextarea(app.LeftPanel);
            app.PROBLEMLOADEDTextArea.Editable = 'off';
            app.PROBLEMLOADEDTextArea.Placeholder = 'None';
            app.IMAXEditFieldLabel = uilabel(app.LeftPanel);
            app.IMAXEditFieldLabel.Text = 'IMAX';
            app.IMAXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IMAXEditField.RoundFractionalValues = 'on';
            app.IMAXEditField.Placeholder = '1';
            app.RMINEditFieldLabel = uilabel(app.LeftPanel);
            app.RMINEditFieldLabel.Text = 'RMIN';
            app.RMINEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RMINEditField.RoundFractionalValues = 'on';
            app.RMINEditField.Placeholder = '1';
            app.RMAXEditFieldLabel = uilabel(app.LeftPanel);
            app.RMAXEditFieldLabel.Text = 'RMAX';
            app.RMAXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RMAXEditField.RoundFractionalValues = 'on';
            app.RMAXEditField.Placeholder = '1';
            app.IMINEditFieldLabel = uilabel(app.LeftPanel);
            app.IMINEditFieldLabel.Text = 'IMIN';
            app.IMINEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IMINEditField.RoundFractionalValues = 'on';
            app.IMINEditField.Placeholder = '1';
            app.EigSearchEditFieldLabel = uilabel(app.LeftPanel);
            app.EigSearchEditFieldLabel.Text = '# Eig Search';
            app.EigSearchEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EigSearchEditField.Limits = [0 Inf];
            app.EigSearchEditField.ValueDisplayFormat = '%.0f';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.NLEVPInformationTab = uitab(app.TabGroup);
            app.NLEVPInformationTab.Title = 'NLEVP Information';
            app.TextArea_3 = uitextarea(app.NLEVPInformationTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Information about the loaded NLEVP (if taken from the NLEVP pack, say)'; 'Each problem has their own "documentation" in matlab comments, though I don''t know if there is a way to extract some relevant bits programatically.'};
            app.ContourTab = uitab(app.TabGroup);
            app.ContourTab.Title = 'Contour';
            app.TypeButtonGroup = uibuttongroup(app.ContourTab);
            app.TypeButtonGroup.Title = 'Type';
            app.CircleButton = uiradiobutton(app.TypeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.EllipseButton = uiradiobutton(app.TypeButtonGroup);
            app.EllipseButton.Text = 'Ellipse';
            app.RectangleButton = uiradiobutton(app.TypeButtonGroup);
            app.RectangleButton.Text = 'Rectangle';
            app.QuadratureButtonGroup = uibuttongroup(app.ContourTab);
            app.QuadratureButtonGroup.Title = 'Quadrature';
            app.TrapezoidButton = uiradiobutton(app.QuadratureButtonGroup);
            app.TrapezoidButton.Text = 'Trapezoid';
            app.TrapezoidButton.Value = true;
            app.GaussianButton = uiradiobutton(app.QuadratureButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.NewtonCotesButton = uiradiobutton(app.QuadratureButtonGroup);
            app.NewtonCotesButton.Text = 'Newton-Cotes';
            app.QuadNodesSliderLabel = uilabel(app.ContourTab);
            app.QuadNodesSliderLabel.Text = 'Quad Nodes';
            app.QuadNodesSlider = uislider(app.ContourTab);
            app.QuadNodesSlider.Limits = [0 512];
            app.ContourParametersButton = uibutton(app.ContourTab, 'push');
            app.ContourParametersButton.Text = 'Contour Parameters';
            app.EigenvalueInformationTab = uitab(app.TabGroup);
            app.EigenvalueInformationTab.Title = 'Eigenvalue Information';
            app.TextArea = uitextarea(app.EigenvalueInformationTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'List of Computed Eigenvalues'; 'Matching Distance to Reference (if provided)'};
            app.ErrorsWarningsTab = uitab(app.TabGroup);
            app.ErrorsWarningsTab.Title = 'Errors/Warnings';
            app.TextArea_2 = uitextarea(app.ErrorsWarningsTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Warnings/Errors'; 'numerical rank warnings during data matrix construction'; ''; 'When warnings present themselves, this tab should "light up red" or something like that.'; 'Maybe warnings in orange and errors in red?'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CIMTOOL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end