classdef ellipseparameters < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        alphaEditField           matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        centerEditField          matlab.ui.control.EditField
        centerEditField_2Label   matlab.ui.control.Label
        betaEditField            matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        numquadnodesEditField    matlab.ui.control.NumericEditField
        quadnodesEditFieldLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        centerUpdate
        quadnodesUpdate
        radiusUpdate
    end

    properties (Access = public)
        N (1,1) double {mustBePositive(N), mustBeNonmissing(N), mustBeNonNan(N), mustBeInteger(N), mustBeFinite(N)} = 8;
        alpha (1,1) double {mustBeReal(alpha), mustBePositive(alpha), mustBeNonzero(alpha), mustBeNonmissing(alpha), mustBeNonNan(alpha), mustBeFinite(alpha)} = 1; % 1/2 horizontal width of ellipse
        beta (1,1) double {mustBeReal(beta), mustBePositive(beta), mustBeNonzero(beta), mustBeNonmissing(beta), mustBeNonNan(beta), mustBeFinite(beta)} = 1; % 1/2 vertical width of ellipse
        center (1,1) double {mustBeNonmissing(center), mustBeNonNan(center), mustBeFinite(center)} = 0+0i;
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: numquadnodesEditField
        function numquadnodesEditFieldValueChanged(comp, event)
            try
                comp.N = comp.numquadnodesEditField.Value;
                notify(comp, 'quadnodesUpdate');
            catch
                update(comp);
                errordlg("Invalid # of quadrature nodes. Please check input and try again.")
            end
        end

        % Value changed function: betaEditField
        function betaEditFieldValueChanged(comp, event)
            try
                comp.alpha = comp.betaEditField.Value;
                notify(comp, 'radiusUpdate');
            catch
                update(comp);
                errordlg("Invalid vertical radius. Please check input and try again.")
            end
        end

        % Callback function: not associated with a component
        function alphaEditFieldValueChanged(comp, event)
            try
                comp.alpha = comp.alphaEditField.Value;
                notify(comp, 'radiusUpdate');
            catch
                update(comp);
                errordlg("Invalid horizontal radius. Please check input and try again.")
            end
        end

        % Value changed function: centerEditField
        function centerEditFieldValueChanged(comp, event)
            try
                comp.center = str2double(comp.centerEditField.Value);
                notify(comp, 'centerUpdate');
            catch
                update(comp);
                errordlg("Invalid center. Please check input and try again.")
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.betaEditField.Value = comp.beta;
            comp.alphaEditField.Value = comp.alpha;
            comp.centerEditField.Value = num2str(comp.center);
            comp.numquadnodesEditField.Value = comp.N;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 367 169];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.quadnodesEditFieldLabel = uilabel(comp.GridLayout);
            comp.quadnodesEditFieldLabel.Text = '# quad nodes';
            comp.numquadnodesEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.numquadnodesEditField.Limits = [0 Inf];
            comp.numquadnodesEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @numquadnodesEditFieldValueChanged, true);
            comp.numquadnodesEditField.Value = 8;
            comp.EditFieldLabel = uilabel(comp.GridLayout);
            comp.EditFieldLabel.Text = 'β';
            comp.betaEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.betaEditField.Limits = [0 Inf];
            comp.betaEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @betaEditFieldValueChanged, true);
            comp.betaEditField.Value = 1;
            comp.centerEditField_2Label = uilabel(comp.GridLayout);
            comp.centerEditField_2Label.Text = 'center';
            comp.centerEditField = uieditfield(comp.GridLayout, 'text');
            comp.centerEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @centerEditFieldValueChanged, true);
            comp.centerEditField.Value = '0';
            comp.Label = uilabel(comp.GridLayout);
            comp.Label.Text = 'α';
            comp.alphaEditField = uieditfield(comp.GridLayout, 'numeric');
            comp.alphaEditField.Limits = [0 Inf];
            comp.alphaEditField.Value = 1;
        end
    end
end